classdef visualize_irradiance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        Panel                    matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        STLsButton               matlab.ui.control.Button
        SunPositionIndexSpinner  matlab.ui.control.Spinner
        SunPositionLabel         matlab.ui.control.Label
        TimeLabel                matlab.ui.control.Label
        IrradianceLabel          matlab.ui.control.Label
        ElevationLabel           matlab.ui.control.Label
        AzimuthLabel             matlab.ui.control.Label
        CellIrradianceCSVButton  matlab.ui.control.Button
        SunPositionCSVButton     matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % CSVs
        sun_position_csv;
        irradiance_csv;
        
        num_cells;

        % Point representations of the canopy and all array cells
        arrayCellPoints;
        canopyPoints;
        colourmap;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SunPositionCSVButton
        function SunPositionCSVButtonPushed(app, event)
            [filename, dir_path] = uigetfile('*.*', 'Select a file');
            full_file_path = [dir_path, filename];

            app.sun_position_csv = readcell(full_file_path);
        end

        % Button pushed function: CellIrradianceCSVButton
        function CellIrradianceCSVButtonPushed(app, event)
            [filename, dir_path] = uigetfile('*.*', 'Select a file');
            full_file_path = [dir_path, filename];

            app.irradiance_csv = readmatrix(full_file_path);

            % Add a colour map
            app.colourmap = jet;
            colormap(app.UIAxes, app.colourmap);

            colour_map_max = max(app.irradiance_csv, [], 'all');
            colour_map_min = min(app.irradiance_csv, [], 'all');
            clim(app.UIAxes, [colour_map_min, colour_map_max]);
            colorbar(app.UIAxes);
        end

        % Value changed function: SunPositionIndexSpinner
        function SunPositionIndexSpinnerValueChanged(app, event)
            
            value = app.SunPositionIndexSpinner.Value;
            % Display the position of the sun as a label
            azimuth = app.sun_position_csv{value,1};
            elevation = app.sun_position_csv{value,2};
            irradiance = app.sun_position_csv{value,3};
            time = app.sun_position_csv{value,4};
            
            app.AzimuthLabel.Text = strcat("Azimuth: ", num2str(azimuth));
            app.ElevationLabel.Text = strcat("Elevation: ", num2str(elevation));
            app.IrradianceLabel.Text = strcat("Irradiance: ", num2str(irradiance));
            app.TimeLabel.Text = strcat("Time: ", datestr(time));

            % Label each cell differently
            cell_irradiances = app.irradiance_csv(value,:);
            for i=1:app.num_cells
                irr = cell_irradiances(i);

                cellTriangles = app.arrayCellPoints{i};
                numPoints = size(cellTriangles, 1);
                if (numPoints == 0)
                    continue;
                end
                
                for j=1:3:numPoints
                    x = cellTriangles(j:j+2,1);
                    y = cellTriangles(j:j+2,2);
                    z = cellTriangles(j:j+2,3);
                    
                    fill3(app.UIAxes, x, y, z, irr, 'FaceColor', 'flat', 'EdgeColor', 'none');
                end
            end
        end

        % Callback function
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            drawnow;
        end

        % Button pushed function: STLsButton
        function STLsButtonPushed(app, event)
            % Plot the array cell STLs
            stl_path = uigetdir('*.*', 'Select a folder for STLs');
            canopyPath = strcat(stl_path, "\canopy.stl");
            arrayCellsPath = strcat(stl_path, "\NA");
            app.num_cells = 257;

            [canopyMesh, arrayCellMeshes, app.canopyPoints, app.arrayCellPoints] = import_canopy_array_stl(canopyPath, arrayCellsPath, app.num_cells);
        
            % Plot the array and canopy
            highlightCells = [];
            plotArrayCanopy(app.canopyPoints, app.arrayCellPoints, highlightCells, app.UIAxes);
            
        end

        % Callback function: not associated with a component
        function CanopySTLButtonPushed(app, event)
            % Plot the canopy STL
            [filename, dir_path] = uigetfile('*.*', 'Select a file');
            canopy_stl = [dir_path, filename];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'There are Voices Inside my Head')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.SunPositionCSVButton = uibutton(app.GridLayout2, 'push');
            app.SunPositionCSVButton.ButtonPushedFcn = createCallbackFcn(app, @SunPositionCSVButtonPushed, true);
            app.SunPositionCSVButton.Text = 'Sun Position CSV';
            app.CellIrradianceCSVButton = uibutton(app.GridLayout2, 'push');
            app.CellIrradianceCSVButton.ButtonPushedFcn = createCallbackFcn(app, @CellIrradianceCSVButtonPushed, true);
            app.CellIrradianceCSVButton.Text = 'Cell Irradiance CSV';
            app.AzimuthLabel = uilabel(app.GridLayout2);
            app.AzimuthLabel.Text = 'Azimuth:';
            app.ElevationLabel = uilabel(app.GridLayout2);
            app.ElevationLabel.Text = 'Elevation:';
            app.IrradianceLabel = uilabel(app.GridLayout2);
            app.IrradianceLabel.Text = 'Irradiance:';
            app.TimeLabel = uilabel(app.GridLayout2);
            app.TimeLabel.Text = 'Time:';
            app.SunPositionLabel = uilabel(app.GridLayout2);
            app.SunPositionLabel.Text = 'Sun Position Index:';
            app.SunPositionIndexSpinner = uispinner(app.GridLayout2);
            app.SunPositionIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @SunPositionIndexSpinnerValueChanged, true);
            app.STLsButton = uibutton(app.GridLayout2, 'push');
            app.STLsButton.ButtonPushedFcn = createCallbackFcn(app, @STLsButtonPushed, true);
            app.STLsButton.Text = 'STLs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualize_irradiance

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end